classdef GuiScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SingleButton          matlab.ui.control.Button
        ReconnectButton       matlab.ui.control.Button
        ContinuousCheckBox    matlab.ui.control.CheckBox
        ChannelDropDownLabel  matlab.ui.control.Label
        ChannelDropDown       matlab.ui.control.DropDown
        FindinstrumentButton  matlab.ui.control.Button
    end


    properties (Access = public)
        Instr % Instrument object
        linked_elem_list={};
    end

    methods (Access = private)
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
        
        function createControlLinks(app)
            linkControlElement(app, ...
                app.ChannelDropDown, 'channel');
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, interface, address, varargin)
            
            %interface='USB';
            %address='0x0699::0x0413::C013397';
            app.Instr = MyScope(interface, address, varargin{:});
            app.ChannelDropDown.ItemsData = str2double(app.ChannelDropDown.Items);
            
            createControlLinks(app);
            readPropertyHedged(app.Instr,'all');
            updateGui(app);
        end

        % Button pushed function: SingleButton
        function SingleButtonPushed(app, event)
            readTrace(app.Instr);
        end

        % Button pushed function: ReconnectButton
        function ReconnectButtonPushed(app, event)
            readPropertyHedged(app.Instr,'all');
        end

        % Value changed function: ContinuousCheckBox
        function ContinuousCheckBoxValueChanged(app, event)
            while app.ContinuousCheckBox.Value
                readTrace(app.Instr);
                pause(1);
            end
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            app.Instr.channel=value;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.Instr);
            delete(app);
        end

        % Button pushed function: FindinstrumentButton
        function FindinstrumentButtonPushed(app, event)
            findInstrumentCallback(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SingleButton = uibutton(app.UIFigure, 'push');
            app.SingleButton.ButtonPushedFcn = createCallbackFcn(app, @SingleButtonPushed, true);
            app.SingleButton.Text = 'Single';
            app.ReconnectButton = uibutton(app.UIFigure, 'push');
            app.ReconnectButton.ButtonPushedFcn = createCallbackFcn(app, @ReconnectButtonPushed, true);
            app.ReconnectButton.Text = 'Reconnect';
            app.ContinuousCheckBox = uicheckbox(app.UIFigure);
            app.ContinuousCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContinuousCheckBoxValueChanged, true);
            app.ContinuousCheckBox.Text = 'Continuous';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'1', '2', '3', '4'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = '1';
            app.FindinstrumentButton = uibutton(app.UIFigure, 'push');
            app.FindinstrumentButton.ButtonPushedFcn = createCallbackFcn(app, @FindinstrumentButtonPushed, true);
            app.FindinstrumentButton.Text = 'Find instrument';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiScope(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end