classdef GuiZiRingdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiRingdownUIFigure            matlab.ui.Figure
        AuxiliaryoutputPanel          matlab.ui.container.Panel
        AuxOutEnableCheckBox          matlab.ui.control.CheckBox
        OnlevelVEditFieldLabel        matlab.ui.control.Label
        OnlevelVEditField             matlab.ui.control.NumericEditField
        OfflevelVEditFieldLabel       matlab.ui.control.Label
        OfflevelVEditField            matlab.ui.control.NumericEditField
        OntimesEditFieldLabel         matlab.ui.control.Label
        OntimesEditField              matlab.ui.control.NumericEditField
        OfftimesEditFieldLabel        matlab.ui.control.Label
        OfftimesEditField             matlab.ui.control.NumericEditField
        AuxoutonLampLabel             matlab.ui.control.Label
        AuxoutonLamp                  matlab.ui.control.Lamp
        AuxOutInfoLabel               matlab.ui.control.Label
        AcquisitionoptionsPanel       matlab.ui.container.Panel
        DownsampledrateptsLabel       matlab.ui.control.Label
        DownsampledrateEditField      matlab.ui.control.NumericEditField
        DownsamplingfactorEditFieldLabel  matlab.ui.control.Label
        DownsamplingfactorEditField   matlab.ui.control.NumericEditField
        DatatransferrateptsEditFieldLabel  matlab.ui.control.Label
        DatatransferrateEditField     matlab.ui.control.NumericEditField
        RingdownPanel                 matlab.ui.container.Panel
        EnabletriggeringButton        matlab.ui.control.StateButton
        TriggerthresholdVEditFieldLabel  matlab.ui.control.Label
        TriggerthresholdVEditField    matlab.ui.control.NumericEditField
        RecordingLampLabel            matlab.ui.control.Label
        RecordingLamp                 matlab.ui.control.Lamp
        RecordtimesEditFieldLabel     matlab.ui.control.Label
        RecordtimesEditField          matlab.ui.control.NumericEditField
        TimeelapsedsEditFieldLabel    matlab.ui.control.Label
        TimeelapsedsEditField         matlab.ui.control.NumericEditField
        DriveButton                   matlab.ui.control.StateButton
        fmeasurementHzEditFieldLabel  matlab.ui.control.Label
        fmeasurementHzEditField       matlab.ui.control.NumericEditField
        DemodulatorspectrumPanel      matlab.ui.container.Panel
        DemodUIAxes                   matlab.ui.control.UIAxes
        FFTRBWHzEditFieldLabel        matlab.ui.control.Label
        FFTRBWHzEditField             matlab.ui.control.NumericEditField
        FFTlengthptEditFieldLabel     matlab.ui.control.Label
        FFTlengthptEditField          matlab.ui.control.NumericEditField
        TraceUIAxes                   matlab.ui.control.UIAxes
        AdaptiveFrequencyPanel        matlab.ui.container.Panel
        AdaptivedemodCheckBox         matlab.ui.control.CheckBox
        OscfollowingLampLabel         matlab.ui.control.Label
        OscfollowingLamp              matlab.ui.control.Lamp
        AveragingPanel                matlab.ui.container.Panel
        NaverageEditFieldLabel        matlab.ui.control.Label
        NaverageEditField             matlab.ui.control.NumericEditField
        CompletedaveragesEditFieldLabel  matlab.ui.control.Label
        CompletedaveragesEditField    matlab.ui.control.NumericEditField
        AutosavingPanel               matlab.ui.container.Panel
        AutosaveCheckBox              matlab.ui.control.CheckBox
        TracenameEditFieldLabel       matlab.ui.control.Label
        TracenameEditField            matlab.ui.control.EditField
        AutosaveLabel                 matlab.ui.control.Label
    end


    properties (Access = public)
        Instr       % Instrument object
        GuiSync
    end
    
    properties (Access = private)
        RingdownLine
        SpectrumLine
        
        % If the limits of Axes should be setup, it is only true before the
        % first data is added
        init_plot = true
    end
    
    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            addLink(app.GuiSync, app.RecordingLamp,               '.Instr.recording');
            addLink(app.GuiSync, app.DriveButton,                 '.Instr.drive_on');
            addLink(app.GuiSync, app.EnabletriggeringButton,      '.Instr.enable_acq');
            addLink(app.GuiSync, app.TriggerthresholdVEditField,  '.Instr.trig_threshold');
            addLink(app.GuiSync, app.RecordtimesEditField,        '.Instr.record_time');
            addLink(app.GuiSync, app.fmeasurementHzEditField,     '.Instr.meas_osc_freq');
            addLink(app.GuiSync, app.TimeelapsedsEditField,       '.Instr.elapsed_t');
            addLink(app.GuiSync, app.DatatransferrateEditField,   '.Instr.demod_rate');
            
            addLink(app.GuiSync, app.NaverageEditField,           '.Instr.n_avg');
            addLink(app.GuiSync, app.CompletedaveragesEditField,  '.Instr.avg_count');
            
            addLink(app.GuiSync, app.FFTlengthptEditField,        '.Instr.fft_length');
            addLink(app.GuiSync, app.FFTRBWHzEditField,           '.Instr.fft_rbw');
            addLink(app.GuiSync, app.DownsamplingfactorEditField, '.Instr.downsample_n');
            addLink(app.GuiSync, app.DownsampledrateEditField,    '.Instr.downsampled_rate');
            
            addLink(app.GuiSync, app.AdaptivedemodCheckBox,       '.Instr.adaptive_meas_osc');
            addLink(app.GuiSync, app.OscfollowingLamp,            '.Instr.ad_osc_following');
            
            addLink(app.GuiSync, app.AutosaveCheckBox,            '.Instr.auto_save');
            addLink(app.GuiSync, app.TracenameEditField,          '.Instr.trace_name');
            
            % Auxiliary output
            addLink(app.GuiSync, app.AuxOutEnableCheckBox,        '.Instr.enable_aux_out');
            addLink(app.GuiSync, app.OnlevelVEditField,           '.Instr.aux_out_on_lev');
            addLink(app.GuiSync, app.OfflevelVEditField,          '.Instr.aux_out_off_lev');
            addLink(app.GuiSync, app.OntimesEditField,            '.Instr.aux_out_on_t');
            addLink(app.GuiSync, app.OfftimesEditField,           '.Instr.aux_out_off_t');
            addLink(app.GuiSync, app.AuxoutonLamp,                '.Instr.aux_out_on');
            
            % X limits are symmetric, also add 1 Hz extra for robustness against x=0
            addLink(app.GuiSync, app.DemodUIAxes,                 '.Instr.demod_rate', ...
                'elem_prop', 'XLim', 'outputProcessingFcn', @(x)([-x/2-1, x/2+1]));
        end
        
        % Switch on/off access to those gui elements that cannot/should not 
        % be changed during the acquisition of ringdown
        function updateAccess(app, state)
            switch lower(state)
                case 'recording'
                    app.OntimesEditField.Editable='off';
                    app.OfftimesEditField.Editable='off';
                    app.AuxOutEnableCheckBox.Enable='off';
                    
                    app.DownsamplingfactorEditField.Editable = 'off';
                    app.DownsampledrateEditField.Editable = 'off';
                case 'driving'
                    app.OntimesEditField.Editable='on';
                    app.OfftimesEditField.Editable='on';
                    app.AuxOutEnableCheckBox.Enable='on';
                    
                    app.DownsamplingfactorEditField.Editable = 'on';
                    app.DownsampledrateEditField.Editable = 'on';
                otherwise
                    error('Unknown state name %s supplied to updateAccess', state)
            end
        end
        
        function newDemodSampleCallback(app, ~, ~)
            if app.init_plot && ~isempty(app.Instr.DemodSpectrum.y) ...
                    && ~all(isnan(app.Instr.DemodSpectrum.y))
                app.init_plot = false;
                
                % Set limits converting to 'double'
                app.DemodUIAxes.YLim = double([0.1*min(app.Instr.DemodSpectrum.y), ...
                    100*max(app.Instr.DemodSpectrum.y)]);
            end
            
            if app.Instr.recording
                
                % If the acquisition of a new ringdown trace is under way,
                % plot it dynamically
                if app.Instr.elapsed_t == 0
                    tmax = 1;
                else
                    tmax = app.Instr.elapsed_t;
                end
                
                app.TraceUIAxes.XLim = [0, tmax];
                
                % Plot the ringdown record
                set(app.RingdownLine, 'XData', app.Instr.Trace.x, 'YData', app.Instr.Trace.y);
            end
            
            % Plot the demodulator spectrum
            set(app.SpectrumLine, 'XData', app.Instr.DemodSpectrum.x, 'YData', app.Instr.DemodSpectrum.y);
        end
        
        function newDataCallback(app, ~, ~)
            
            % Plot the ringdown record
            set(app.RingdownLine, 'XData', app.Instr.Trace.x, 'YData', app.Instr.Trace.y);
                
            updateAccess(app, 'driving');
            updateAll(app.GuiSync);
        end
        
        function recordingStartedCallback(app, ~, ~)
            updateAccess(app, 'recording')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Instrument, varargin)
            app.Instr = Instrument;
            app.GuiSync = MyGuiSync(app, 'KernelObj', Instrument);
            
            % Disable all axes interactions
            disableDefaultInteractivity(app.DemodUIAxes)
            app.DemodUIAxes.Toolbar = [];
            axtoolbar(app.DemodUIAxes, {'zoomin','zoomout'});
            
            disableDefaultInteractivity(app.TraceUIAxes)
            app.TraceUIAxes.Toolbar.Visible = 'off';
            
            % Create empty plot lines 
            app.RingdownLine = line(app.TraceUIAxes, 'XData', [], 'YData', [], 'Color', 'blue');
            app.SpectrumLine = line(app.DemodUIAxes, 'XData', [], 'YData', [], 'Color', 'blue');
            
            createLinks(app);
            
            L = addlistener(app.Instr, 'NewDemodSample', @app.newDemodSampleCallback);
            addToCleanup(app.GuiSync, L);
            
            L = addlistener(app.Instr, 'NewData', @app.newDataCallback);
            addToCleanup(app.GuiSync, L);
            
            L = addlistener(app.Instr, 'RecordingStarted', @app.recordingStartedCallback);
            addToCleanup(app.GuiSync, L);
            
            updateAll(app.GuiSync);
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end

        % Value changed function: EnabletriggeringButton
        function EnabletriggeringButtonValueChanged(app, event)
            value = app.EnabletriggeringButton.Value;
            if value && ~app.Instr.recording
                app.Instr.drive_on = true;
                resetAveraging(app.Instr); % Set the averaging counter to 0
            else
                app.Instr.drive_on = false;
            end
            app.Instr.enable_acq = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiRingdownUIFigure = uifigure('Visible', 'off');
            app.ZiRingdownUIFigure.Name = 'Template';
            app.AuxiliaryoutputPanel = uipanel(app.ZiRingdownUIFigure);
            app.AuxiliaryoutputPanel.Title = 'Auxiliary output';
            app.AuxOutEnableCheckBox = uicheckbox(app.AuxiliaryoutputPanel);
            app.AuxOutEnableCheckBox.Text = 'Enable';
            app.OnlevelVEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OnlevelVEditFieldLabel.Text = 'On level (V)';
            app.OnlevelVEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.OfflevelVEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OfflevelVEditFieldLabel.Text = 'Off level (V)';
            app.OfflevelVEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.OntimesEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OntimesEditFieldLabel.Text = 'On time (s)';
            app.OntimesEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.OfftimesEditFieldLabel = uilabel(app.AuxiliaryoutputPanel);
            app.OfftimesEditFieldLabel.Text = 'Off time (s)';
            app.OfftimesEditField = uieditfield(app.AuxiliaryoutputPanel, 'numeric');
            app.AuxoutonLampLabel = uilabel(app.AuxiliaryoutputPanel);
            app.AuxoutonLampLabel.Text = 'Aux out on';
            app.AuxoutonLamp = uilamp(app.AuxiliaryoutputPanel);
            app.AuxOutInfoLabel = uilabel(app.AuxiliaryoutputPanel);
            app.AuxOutInfoLabel.Text = {'If disabled – aux out always ''on'''; 'If enabled, switch between ''on'' and ''off'' states'; 'periodically starting from ''on'' state '};
            app.AcquisitionoptionsPanel = uipanel(app.ZiRingdownUIFigure);
            app.AcquisitionoptionsPanel.Title = 'Acquisition options';
            app.DownsampledrateptsLabel = uilabel(app.AcquisitionoptionsPanel);
            app.DownsampledrateptsLabel.Text = 'Downsampled rate (pt/s)';
            app.DownsampledrateEditField = uieditfield(app.AcquisitionoptionsPanel, 'numeric');
            app.DownsampledrateEditField.ValueDisplayFormat = '%.0f';
            app.DownsamplingfactorEditFieldLabel = uilabel(app.AcquisitionoptionsPanel);
            app.DownsamplingfactorEditFieldLabel.Text = 'Downsampling factor';
            app.DownsamplingfactorEditField = uieditfield(app.AcquisitionoptionsPanel, 'numeric');
            app.DownsamplingfactorEditField.ValueDisplayFormat = '%.0f';
            app.DatatransferrateptsEditFieldLabel = uilabel(app.AcquisitionoptionsPanel);
            app.DatatransferrateptsEditFieldLabel.Text = 'Data transfer rate (pt/s)';
            app.DatatransferrateEditField = uieditfield(app.AcquisitionoptionsPanel, 'numeric');
            app.DatatransferrateEditField.ValueDisplayFormat = '%.0f';
            app.RingdownPanel = uipanel(app.ZiRingdownUIFigure);
            app.RingdownPanel.Title = 'Ringdown';
            app.EnabletriggeringButton = uibutton(app.RingdownPanel, 'state');
            app.EnabletriggeringButton.ValueChangedFcn = createCallbackFcn(app, @EnabletriggeringButtonValueChanged, true);
            app.EnabletriggeringButton.Text = 'Enable triggering';
            app.TriggerthresholdVEditFieldLabel = uilabel(app.RingdownPanel);
            app.TriggerthresholdVEditFieldLabel.Text = 'Trigger threshold (V)';
            app.TriggerthresholdVEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.TriggerthresholdVEditField.Value = 1;
            app.RecordingLampLabel = uilabel(app.RingdownPanel);
            app.RecordingLampLabel.Text = 'Recording';
            app.RecordingLamp = uilamp(app.RingdownPanel);
            app.RecordtimesEditFieldLabel = uilabel(app.RingdownPanel);
            app.RecordtimesEditFieldLabel.Text = 'Record time (s)';
            app.RecordtimesEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.RecordtimesEditField.Value = 150;
            app.TimeelapsedsEditFieldLabel = uilabel(app.RingdownPanel);
            app.TimeelapsedsEditFieldLabel.Text = 'Time elapsed (s)';
            app.TimeelapsedsEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.TimeelapsedsEditField.ValueDisplayFormat = '%.1f';
            app.TimeelapsedsEditField.Editable = 'off';
            app.DriveButton = uibutton(app.RingdownPanel, 'state');
            app.DriveButton.Text = 'Drive';
            app.fmeasurementHzEditFieldLabel = uilabel(app.RingdownPanel);
            app.fmeasurementHzEditFieldLabel.Text = 'f measurement (Hz)';
            app.fmeasurementHzEditField = uieditfield(app.RingdownPanel, 'numeric');
            app.fmeasurementHzEditField.Editable = 'off';
            app.fmeasurementHzEditField.Value = 1200300;
            app.DemodulatorspectrumPanel = uipanel(app.ZiRingdownUIFigure);
            app.DemodulatorspectrumPanel.Title = 'Demodulator spectrum';
            app.DemodUIAxes = uiaxes(app.DemodulatorspectrumPanel);
            xlabel(app.DemodUIAxes, 'Frequency (Hz)')
            ylabel(app.DemodUIAxes, 'PSD')
            app.DemodUIAxes.XLim = [0 1];
            app.DemodUIAxes.YLim = [0.1 1];
            app.DemodUIAxes.YScale = 'log';
            app.FFTRBWHzEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.FFTRBWHzEditFieldLabel.Text = 'FFT RBW (Hz)';
            app.FFTRBWHzEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.FFTRBWHzEditField.Value = 1000;
            app.FFTlengthptEditFieldLabel = uilabel(app.DemodulatorspectrumPanel);
            app.FFTlengthptEditFieldLabel.Text = 'FFT length (pt)';
            app.FFTlengthptEditField = uieditfield(app.DemodulatorspectrumPanel, 'numeric');
            app.FFTlengthptEditField.Value = 16;
            app.TraceUIAxes = uiaxes(app.ZiRingdownUIFigure);
            xlabel(app.TraceUIAxes, 'Time (s)')
            ylabel(app.TraceUIAxes, 'Magnitude r (V)')
            app.TraceUIAxes.XLim = [0 1];
            app.TraceUIAxes.YScale = 'log';
            app.AdaptiveFrequencyPanel = uipanel(app.ZiRingdownUIFigure);
            app.AdaptiveFrequencyPanel.Title = 'Adaptive demodulation frequency';
            app.AdaptivedemodCheckBox = uicheckbox(app.AdaptiveFrequencyPanel);
            app.AdaptivedemodCheckBox.Text = 'Enable';
            app.OscfollowingLampLabel = uilabel(app.AdaptiveFrequencyPanel);
            app.OscfollowingLampLabel.Text = 'Osc following';
            app.OscfollowingLamp = uilamp(app.AdaptiveFrequencyPanel);
            app.AveragingPanel = uipanel(app.ZiRingdownUIFigure);
            app.AveragingPanel.Title = 'Averaging';
            app.NaverageEditFieldLabel = uilabel(app.AveragingPanel);
            app.NaverageEditFieldLabel.Text = 'N average';
            app.NaverageEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.NaverageEditField.Value = 1;
            app.CompletedaveragesEditFieldLabel = uilabel(app.AveragingPanel);
            app.CompletedaveragesEditFieldLabel.Text = 'Completed averages';
            app.CompletedaveragesEditField = uieditfield(app.AveragingPanel, 'numeric');
            app.CompletedaveragesEditField.ValueDisplayFormat = '%.0f';
            app.CompletedaveragesEditField.Editable = 'off';
            app.AutosavingPanel = uipanel(app.ZiRingdownUIFigure);
            app.AutosavingPanel.Title = 'Auto saving';
            app.AutosaveCheckBox = uicheckbox(app.AutosavingPanel);
            app.AutosaveCheckBox.Text = 'Auto save each trace';
            app.TracenameEditFieldLabel = uilabel(app.AutosavingPanel);
            app.TracenameEditFieldLabel.Text = 'Trace name';
            app.TracenameEditField = uieditfield(app.AutosavingPanel, 'text');
            app.AutosaveLabel = uilabel(app.AutosavingPanel);
            app.AutosaveLabel.Text = '(Requires connection with DAQ)';
            % Show the figure after all components are created
            app.ZiRingdownUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiZiRingdown(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiRingdownUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiRingdownUIFigure)
        end
    end
end