classdef GuiZiScopeFt < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ZiScopeFt                      matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        AvgtypeDropDownLabel           matlab.ui.control.Label
        AvgtypeDropDown                matlab.ui.control.DropDown
        navgEditFieldLabel             matlab.ui.control.Label
        navgEditField                  matlab.ui.control.NumericEditField
        ContinuousCheckBox             matlab.ui.control.CheckBox
        RestartaveragingButton         matlab.ui.control.Button
        NumberofpointsEditFieldLabel   matlab.ui.control.Label
        NumberofpointsEditField        matlab.ui.control.NumericEditField
        FFTRBWEditFieldLabel           matlab.ui.control.Label
        FFTRBWEditField                matlab.ui.control.NumericEditField
        SamplingrateptsEditFieldLabel  matlab.ui.control.Label
        SamplingrateptsEditField       matlab.ui.control.NumericEditField
        ylimitmodeLabel                matlab.ui.control.Label
        YLimModeDropDown               matlab.ui.control.DropDown
        flimitmodeLabel                matlab.ui.control.Label
        XLimModeDropDown               matlab.ui.control.DropDown
        yminEditField                  matlab.ui.control.NumericEditField
        ymaxEditField                  matlab.ui.control.NumericEditField
        fmaxHzEditField                matlab.ui.control.NumericEditField
        ScopenumberEditFieldLabel      matlab.ui.control.Label
        ScopenumberEditField           matlab.ui.control.NumericEditField
        SignalinputEditFieldLabel      matlab.ui.control.Label
        SignalinputEditField           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        % Necessary variables for instrument Gui
        Instr % Instrument object
        linked_elem_list={};
        name = ''  % App name to clear on exit
        Listeners = struct()
    end
    
    
    methods (Access = public)
        
        % Automatic callback assignment function
        function cb = createGenericCallback(app)
            cb = createCallbackFcn(app,...
                @(src, event)genericValueChanged(src, event), true);
        end
    
    end
    
    methods (Access = private)
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            linkGuiElement(app, app.DriveLamp, '.Instr.drive_on');
            linkGuiElement(app, app.EnabletriggeringButton, '.Instr.enable_acq');
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Instr = Instrument;
            createLinks(app);
            
            app.Listeners.NewDemodSample=addlistener(app.Instr,'NewDemodSample',@(~,~)updatePlot(app));
            app.Listeners.NewData=addlistener(app.Instr,'NewData',@(~,~)newDataCallback(app));
            app.Listeners.NewSetting=addlistener(app.Instr,'NewSetting',@(~,~)updateGui(app));
            app.Listeners.RecordingStarted=addlistener(app.Instr,'RecordingStarted', ...
                @(~,~)updateAccess(app, 'recording'));
            
            updateGui(app);
            
            % Start continuous data acquisition
            startPoll(app.Instr);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ZiScopeFt = uifigure;
            app.ZiScopeFt.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.ZiScopeFt);
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'PSD (V^2/Hz)')
            app.UIAxes.YScale = 'log';
            app.AvgtypeDropDownLabel = uilabel(app.ZiScopeFt);
            app.AvgtypeDropDownLabel.Text = 'Avg type';
            app.AvgtypeDropDown = uidropdown(app.ZiScopeFt);
            app.AvgtypeDropDown.Items = {'linear', 'exp'};
            app.AvgtypeDropDown.Value = 'linear';
            app.navgEditFieldLabel = uilabel(app.ZiScopeFt);
            app.navgEditFieldLabel.Text = 'n avg';
            app.navgEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ContinuousCheckBox = uicheckbox(app.ZiScopeFt);
            app.ContinuousCheckBox.Text = 'Continuous';
            app.RestartaveragingButton = uibutton(app.ZiScopeFt, 'push');
            app.RestartaveragingButton.Text = 'Restart averaging';
            app.NumberofpointsEditFieldLabel = uilabel(app.ZiScopeFt);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.NumberofpointsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpointsEditField.Value = 5000000;
            app.FFTRBWEditFieldLabel = uilabel(app.ZiScopeFt);
            app.FFTRBWEditFieldLabel.Text = 'FFT RBW';
            app.FFTRBWEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.SamplingrateptsEditFieldLabel = uilabel(app.ZiScopeFt);
            app.SamplingrateptsEditFieldLabel.Text = 'Sampling rate (pt/s)';
            app.SamplingrateptsEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ylimitmodeLabel = uilabel(app.ZiScopeFt);
            app.ylimitmodeLabel.Text = 'y limit mode';
            app.YLimModeDropDown = uidropdown(app.ZiScopeFt);
            app.YLimModeDropDown.Items = {'auto', 'manual'};
            app.YLimModeDropDown.Value = 'manual';
            app.flimitmodeLabel = uilabel(app.ZiScopeFt);
            app.flimitmodeLabel.Text = 'f limit mode';
            app.XLimModeDropDown = uidropdown(app.ZiScopeFt);
            app.XLimModeDropDown.Items = {'auto', 'manual'};
            app.XLimModeDropDown.Value = 'manual';
            app.yminEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ymaxEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.fmaxHzEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ScopenumberEditFieldLabel = uilabel(app.ZiScopeFt);
            app.ScopenumberEditFieldLabel.Text = 'Scope number';
            app.ScopenumberEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.ScopenumberEditField.Editable = 'off';
            app.SignalinputEditFieldLabel = uilabel(app.ZiScopeFt);
            app.SignalinputEditFieldLabel.Text = 'Signal input';
            app.SignalinputEditField = uieditfield(app.ZiScopeFt, 'numeric');
            app.SignalinputEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiZiScopeFt

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ZiScopeFt)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ZiScopeFt)
        end
    end
end